classdef lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Lab02SealesPrototipoLabel   matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        MostrarFuncionesButton      matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        ftTab                       matlab.ui.container.Tab
        TipodeSealDropDownLabel     matlab.ui.control.Label
        TipodeSealDropDown          matlab.ui.control.DropDown
        Panel_2                     matlab.ui.container.Panel
        aLabel                      matlab.ui.control.Label
        FrecuenciaEditFieldLabel    matlab.ui.control.Label
        FrecuenciaEditField         matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        tEditFieldLabel             matlab.ui.control.Label
        tEditField                  matlab.ui.control.NumericEditField
        htTab                       matlab.ui.container.Tab
        TipodeSealDropDown_2Label   matlab.ui.control.Label
        TipodeSealDropDown_2        matlab.ui.control.DropDown
        Panel_4                     matlab.ui.container.Panel
        aLabel_2                    matlab.ui.control.Label
        FrecuenciaEditField_2Label  matlab.ui.control.Label
        FrecuenciaEditField_2       matlab.ui.control.NumericEditField
        AmplitudEditField_2Label    matlab.ui.control.Label
        AmplitudEditField_2         matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        tEditField_2Label           matlab.ui.control.Label
        tEditField_2                matlab.ui.control.NumericEditField
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        ConvolucionButton           matlab.ui.control.Button
        DiscretaButton              matlab.ui.control.StateButton
    end

    
    methods (Access = private)
        
        function setf(~,xval,yval)
            global f
            f=[xval;yval];
        end


        function seth(~,xval,yval)
            global h 
            h=[xval;yval];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Panel_2, TipodeSealDropDown
        function DropDownValueChanged(app, event)
            value = app.TipodeSealDropDown.Value;
            
            
            app.FrecuenciaEditField.Visible=true;
            app.FrecuenciaEditFieldLabel.Visible=true;
            app.AmplitudEditField.Visible=true;
            app.AmplitudEditFieldLabel.Visible=true;
            
            
            switch value
                case " "
                    app.Panel_2.Visible = false;
                    
                case "Seno"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text = "Frecuencia";
                    app.AmplitudEditFieldLabel.Text = "Amplitud";
                    if app.DiscretaButton.Value
                      app.aLabel.Text = "y[n]=A*Sen(2*π*[n]/No)";
                    else
                      app.aLabel.Text = "y(t)=A*Sen(2*π*f*t)";
                    end
                    app.aLabel.Visible = true;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Cuadrada"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="Frecuencia";
                    app.AmplitudEditFieldLabel.Text="Amplitud";
                    app.aLabel.Visible = false;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Exponencial"
                    app.Panel_2.Visible = true;
                    if app.DiscretaButton.Value
                      app.aLabel.Text = "y[n]=A*exp(-b*n)";
                      app.tEditFieldLabel.Text="< n <";
                    else
                      app.aLabel.Text = "y(t)=A*exp(-b*t)";
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="A";
                    app.AmplitudEditFieldLabel.Text="b";
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                case "Rampa 1"
                    app.Panel_2.Visible = true;
                    if app.DiscretaButton.Value
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="a";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                                
                case "Triangular"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="Frecuencia";
                    app.AmplitudEditFieldLabel.Text="Amplitud";
                    app.aLabel.Visible = false;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Rampa 2"
                    app.Panel_2.Visible = true;
                    if app.DiscretaButton.Value
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="m";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";                    
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                    
                case "Rampa 3"
                    app.Panel_2.Visible = true;
                    if app.DiscretaButton.Value
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="m";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";                    
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                    
      
            end


        end

        % Button pushed function: MostrarFuncionesButton
        function MostrarFuncionesButtonPushed(app, event)
            
            if (app.TipodeSealDropDown.Value~=" " && app.TipodeSealDropDown_2~=" ")
            
                
            else
                
                
            end
        end

        % Value changed function: TipodeSealDropDown_2
        function TipodeSealDropDown_2ValueChanged(app, event)
            value = app.TipodeSealDropDown_2.Value;
            app.FrecuenciaEditField_2.Visible=true;
            app.FrecuenciaEditField_2Label.Visible=true;
            app.AmplitudEditField_2.Visible=true;
            app.AmplitudEditField_2Label.Visible=true;
            
            switch value
                case " "
                    app.Panel_4.Visible = false;
                    
                case "Seno"
                    app.Panel_4.Visible = true;
                    app.FrecuenciaEditField_2Label.Text = "Frecuencia";
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    if app.DiscretaButton.Value
                      app.aLabel_2.Text = "y[n]=A*Sen(2*π*[n]/No)";
                    else
                      app.aLabel_2.Text = "y(t)=A*Sen(2*π*f*t)";
                    end
                    app.aLabel_2.Visible = true;
                    app.tEditField_2.Visible = false;
                    app.EditField_2.Visible = false;
                    app.tEditField_2Label.Visible = false;
                    
                case "Cuadrada"
                    app.Panel_4.Visible = true;
                    app.FrecuenciaEditField_2Label.Text = "Frecuencia";
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    app.aLabel_2.Visible = false;
                    app.tEditField_2.Visible = false;
                    app.EditField_2.Visible = false;
                    app.tEditField_2Label.Visible = false;
                    
                case "Exponencial"
                    app.Panel_4.Visible = true;
                    if app.DiscretaButton.Value
                      app.aLabel_2.Text = "y[n]=A*exp(-b*n)";
                      app.tEditField_2Label.Text="< n <";
                    else
                      app.aLabel_2.Text = "y(t)=A*exp(-b*t)";
                      app.tEditField_2Label.Text="< t <";
                    end
                     
                    app.aLabel_2.Visible = true;
                    app.FrecuenciaEditField_2Label.Text="A";
                    app.AmplitudEditField_2Label.Text="b";
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Rampa 1"
                    app.Panel_4.Visible = true;
                    if app.DiscretaButton.Value
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end

                    app.aLabel_2.Visible = false;
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.FrecuenciaEditField_2Label.Text="a";
                    app.AmplitudEditField_2Label.Text="b";
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Triangular"
                    app.Panel_4.Visible = true;
                    app.FrecuenciaEditField_2Label.Text = "Frecuencia";
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    app.aLabel_2.Visible = false;
                    app.tEditField_2.Visible = false;
                    app.EditField_2.Visible = false;
                    app.tEditField_2Label.Visible = false;
                    
                case "Rampa 2"
                    app.Panel_4.Visible = true;
                    
                    if app.DiscretaButton.Value
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = false;
                    app.FrecuenciaEditField_2Label.Text="m";
                    app.AmplitudEditField_2Label.Text="b";
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Rampa 3"
                    app.Panel_4.Visible = true;
                    if app.DiscretaButton.Value
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = false;
                    app.FrecuenciaEditField_2Label.Text="m";
                    app.AmplitudEditField_2Label.Text="b";
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;


            end
            
            
        end

        % Value changed function: DiscretaButton
        function DiscretaButtonValueChanged(app, event)
            value = app.DiscretaButton.Value;
            if value
                app.ftTab.Title="f[n]";
                app.htTab.Title="h[n]";
                app.UIAxes3.Title.String="f[n]";
                app.UIAxes.Title.String="h[n]";
            else
                app.ftTab.Title="f(t)";
                app.htTab.Title="h(t)";
                app.UIAxes3.Title.String="f(t)";
                app.UIAxes.Title.String="h(t)";
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lab02SealesPrototipoLabel = uilabel(app.UIFigure);
            app.Lab02SealesPrototipoLabel.Text = 'Lab02 Señales Prototipo';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'f(t)')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'Y')
            app.MostrarFuncionesButton = uibutton(app.UIFigure, 'push');
            app.MostrarFuncionesButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarFuncionesButtonPushed, true);
            app.MostrarFuncionesButton.Text = 'Mostrar Funciones';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'h(t)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ftTab = uitab(app.TabGroup);
            app.ftTab.Title = 'f(t)';
            app.TipodeSealDropDownLabel = uilabel(app.ftTab);
            app.TipodeSealDropDownLabel.Text = 'Tipo de Señal';
            app.TipodeSealDropDown = uidropdown(app.ftTab);
            app.TipodeSealDropDown.Items = {' ', 'Seno', 'Exponencial', 'Cuadrada', 'Triangular', 'Pulso', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.TipodeSealDropDown.Value = ' ';
            app.Panel_2 = uipanel(app.ftTab);
            app.Panel_2.Visible = 'off';
            app.Panel_2.SizeChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.aLabel = uilabel(app.Panel_2);
            app.aLabel.Text = 'a';
            app.FrecuenciaEditFieldLabel = uilabel(app.Panel_2);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.Panel_2, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.Panel_2);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.tEditFieldLabel = uilabel(app.Panel_2);
            app.tEditFieldLabel.Text = '< t <';
            app.tEditField = uieditfield(app.Panel_2, 'numeric');
            app.htTab = uitab(app.TabGroup);
            app.htTab.Title = 'h(t)';
            app.TipodeSealDropDown_2Label = uilabel(app.htTab);
            app.TipodeSealDropDown_2Label.Text = 'Tipo de Señal';
            app.TipodeSealDropDown_2 = uidropdown(app.htTab);
            app.TipodeSealDropDown_2.Items = {' ', 'Seno', 'Exponencial', 'Cuadrada', 'Triangular', 'Pulso', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TipodeSealDropDown_2ValueChanged, true);
            app.TipodeSealDropDown_2.Value = ' ';
            app.Panel_4 = uipanel(app.htTab);
            app.Panel_4.Visible = 'off';
            app.aLabel_2 = uilabel(app.Panel_4);
            app.aLabel_2.Text = 'a';
            app.FrecuenciaEditField_2Label = uilabel(app.Panel_4);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.AmplitudEditField_2Label = uilabel(app.Panel_4);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.AmplitudEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.tEditField_2Label = uilabel(app.Panel_4);
            app.tEditField_2Label.Text = '< t <';
            app.tEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Posicion'; ''})
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, {'Convolucion'; ''})
            xlabel(app.UIAxes2_2, 't')
            ylabel(app.UIAxes2_2, 'Y')
            app.ConvolucionButton = uibutton(app.UIFigure, 'push');
            app.ConvolucionButton.Text = 'Convolucion';
            app.DiscretaButton = uibutton(app.UIFigure, 'state');
            app.DiscretaButton.ValueChangedFcn = createCallbackFcn(app, @DiscretaButtonValueChanged, true);
            app.DiscretaButton.Text = 'Discreta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end